classdef cost_advisor_plus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OrderPanel                      matlab.ui.container.Panel
        MaterialSelectionLabel          matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        GradeDropDownLabel              matlab.ui.control.Label
        GradeDropDown                   matlab.ui.control.DropDown
        ClassLabel                      matlab.ui.control.Label
        ClassDropDown                   matlab.ui.control.DropDown
        QuantityEditFieldLabel          matlab.ui.control.Label
        QuantityEditField               matlab.ui.control.NumericEditField
        ModelPanel                      matlab.ui.container.Panel
        LoadfileButton                  matlab.ui.control.Button
        FilepathStateLampLabel          matlab.ui.control.Label
        FilepathStateLamp               matlab.ui.control.Lamp
        ProcessfileButton               matlab.ui.control.Button
        BoxDimensionsPanel              matlab.ui.container.Panel
        LengthEditFieldLabel            matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        VolumeEditFieldLabel            matlab.ui.control.Label
        VolumeEditField                 matlab.ui.control.NumericEditField
        GeometryPanel                   matlab.ui.container.Panel
        WeightEditFieldLabel            matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        SurfaceareaEditFieldLabel       matlab.ui.control.Label
        SurfaceareaEditField            matlab.ui.control.NumericEditField
        PartvolumeEditFieldLabel        matlab.ui.control.Label
        PartvolumeEditField             matlab.ui.control.NumericEditField
        CorevolumeEditFieldLabel        matlab.ui.control.Label
        CorevolumeEditField             matlab.ui.control.NumericEditField
        RiggingPanel                    matlab.ui.container.Panel
        FeedercountEditFieldLabel       matlab.ui.control.Label
        FeedercountEditField            matlab.ui.control.NumericEditField
        SaveanalysisButton              matlab.ui.control.Button
        CostPanel                       matlab.ui.container.Panel
        PerPartLabel                    matlab.ui.control.Label
        PerOrderLabel                   matlab.ui.control.Label
        TotalLabel                      matlab.ui.control.Label
        PartLabel                       matlab.ui.control.Label
        MaterialCostLabel               matlab.ui.control.Label
        ProcessingLabel                 matlab.ui.control.Label
        NDTLabel                        matlab.ui.control.Label
        ToolingLabel                    matlab.ui.control.Label
        FixturingLabel                  matlab.ui.control.Label
        StraighteningLabel              matlab.ui.control.Label
        CheckLabel                      matlab.ui.control.Label
        GrandTotalPerPartEditField      matlab.ui.control.NumericEditField
        GrandTotalPerOrderEditField     matlab.ui.control.NumericEditField
        PartSubtotalPerPartEditField    matlab.ui.control.NumericEditField
        PartSubtotalPerOrderEditField   matlab.ui.control.NumericEditField
        MaterialPerPartEditField        matlab.ui.control.NumericEditField
        MaterialPerOrderEditField       matlab.ui.control.NumericEditField
        ProcessingPerPartEditField      matlab.ui.control.NumericEditField
        ProcessingPerOrderEditField     matlab.ui.control.NumericEditField
        NdtPerPartEditField             matlab.ui.control.NumericEditField
        NdtPerOrderEditField            matlab.ui.control.NumericEditField
        ToolingPerPartEditField         matlab.ui.control.NumericEditField
        ToolingPerOrderEditField        matlab.ui.control.NumericEditField
        FixturingSubtotalPerPartEditField  matlab.ui.control.NumericEditField
        FixturingSubtotalPerOrderEditField  matlab.ui.control.NumericEditField
        StraighteningPerPartEditField   matlab.ui.control.NumericEditField
        StraighteningPerOrderEditField  matlab.ui.control.NumericEditField
        CheckPerPartEditField           matlab.ui.control.NumericEditField
        CheckPerOrderEditField          matlab.ui.control.NumericEditField
        GrandTotalPerPartUpperCI        matlab.ui.control.NumericEditField
        GrandTotalPerPartLowerCI        matlab.ui.control.NumericEditField
        GrandTotalPerOrderUpperCI       matlab.ui.control.NumericEditField
        GrandTotalPerOrderLowerCI       matlab.ui.control.NumericEditField
        ExportresultsButton             matlab.ui.control.Button
        ProgramSettingsPanel            matlab.ui.container.Panel
        MeshElementsEditFieldLabel      matlab.ui.control.Label
        MeshElementsEditField           matlab.ui.control.NumericEditField
        ThermalProfileCheckBox          matlab.ui.control.CheckBox
    end

    properties (Access = private)
        cost_controller
        geometry_controller
        order_controller
        path_controller
        setting_controller
        export_controller
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Pathing
            if isdeployed()
                try
                    root_folder = get_executable_folder();
                catch e
                    uiblockingalert( ...
                        app.UIFigure, ...
                        e.message, ...
                        "Cost Advisor Model Error" ...
                        );
                    closereq();
                    return;
                end
            else
                root_folder = fileparts( mfilename( "fullpath" ) );
                root_folder = fileparts( fileparts( root_folder ) );
            end
            resource_folder = fullfile( root_folder, "res" );
            
            % Diary to log anything wrong
            try
                diary( fullfile( root_folder, "err.log" ) );
                diary_closer = onCleanup( @()diary( "off" ) );
                dt = datetime( "now" );
                dt.Format = "yyyy-MM-dd HH:mm:ss.S";
                fprintf( 1, strjoin( [ string( dt ) newline ], "" ) );
            catch
                uialert( ...
                    app.UIFigure, ...
                    "Error with log file, will not be able to record future errors this session.", ...
                    "Log File Error" ...
                    );
            end
            
            % Models
            cost_advisor_file_path = fullfile( ...
                resource_folder, ...
                "cost_advisor.xlsx" ...
                );
            try
                cam = CostAdvisorModel( cost_advisor_file_path );
            catch e
                disp( getReport( e ) );
                uiblockingalert( ...
                    app.UIFigure, ...
                    "Error with Cost Advisor. Unable to proceed, the application will now close.", ...
                    "Cost Advisor Model Error" ...
                    );
                closereq();
                return;
            end
            
            SETTINGS_FILE_NAME = "settings.json";
            settings_file_path = fullfile( resource_folder, SETTINGS_FILE_NAME );
            try
                tm = ToolsuiteModel( ...
                    resource_folder, ...
                    settings_file_path ...
                    );
            catch e
                disp( getReport( e ) );
                uiblockingalert( ...
                    app.UIFigure, ...
                    "Error with Casting Geometric Toolsuite. Unable to proceed, the application will now close.", ...
                    "Toolsuite Model Error" ...
                    );
                closereq();
                return;
            end
            
            complexity_model_file_path = fullfile( ...
                resource_folder, ...
                "complexity_model.mat" ...
                );
            try
                cm = ComplexityModel( complexity_model_file_path );
            catch e
                disp( getReport( e ) );
                uiblockingalert( ...
                    app.UIFigure, ...
                    "Error with Complexity Model. Unable to proceed, the application will now close.", ...
                    "Complexity Model Error" ...
                    );
                closereq();
                return;
            end
            
            % Controllers
            try
                cost_tags = { ...
                    CostController.GRAND_TOTAL ...
                    CostController.PART_SUBTOTAL ...
                    CostController.MATERIAL ...
                    CostController.PROCESSING ...
                    CostController.NDT ...
                    CostController.TOOLING ...
                    CostController.FIXTURING_SUBTOTAL ...
                    CostController.STRAIGHTENING ...
                    CostController.CHECK ...
                    };
                per_part_values = { ...
                    app.GrandTotalPerPartEditField ...
                    app.PartSubtotalPerPartEditField ...
                    app.MaterialPerPartEditField ...
                    app.ProcessingPerPartEditField ...
                    app.NdtPerPartEditField ...
                    app.ToolingPerPartEditField ...
                    app.FixturingSubtotalPerPartEditField ...
                    app.StraighteningPerPartEditField ...
                    app.CheckPerPartEditField ...
                    };
                per_part_edit_fields = containers.Map( cost_tags, per_part_values );
                per_order_values = { ...
                    app.GrandTotalPerOrderEditField ...
                    app.PartSubtotalPerOrderEditField ...
                    app.MaterialPerOrderEditField ...
                    app.ProcessingPerOrderEditField ...
                    app.NdtPerOrderEditField ...
                    app.ToolingPerOrderEditField ...
                    app.FixturingSubtotalPerOrderEditField ...
                    app.StraighteningPerOrderEditField ...
                    app.CheckPerOrderEditField ...
                    };
                per_order_edit_fields = containers.Map( cost_tags, per_order_values );
                grand_total_part_range_edit_fields = [ ...
                    app.GrandTotalPerPartLowerCI ...
                    app.GrandTotalPerPartUpperCI ...
                    ];
                grand_total_order_range_edit_fields = [ ...
                    app.GrandTotalPerOrderLowerCI ...
                    app.GrandTotalPerOrderUpperCI ...
                    ];
                cc = CostController( ...
                    cam, ...
                    cm, ...
                    tm, ...
                    per_part_edit_fields, ...
                    per_order_edit_fields, ...
                    grand_total_part_range_edit_fields, ...
                    grand_total_order_range_edit_fields ...
                    );
                
                geometry_tags = { ...
                    GeometryController.BOX_HEIGHT ...
                    GeometryController.BOX_LENGTH ...
                    GeometryController.BOX_VOLUME ...
                    GeometryController.BOX_WIDTH ...
                    GeometryController.CORE_VOLUME ...
                    GeometryController.FEEDER_COUNT ...
                    GeometryController.CASTING_SURFACE_AREA ...
                    GeometryController.CASTING_VOLUME ...
                    GeometryController.CASTING_WEIGHT ...
                    };
                geometry_values = { ...
                    app.HeightEditField ...
                    app.LengthEditField ...
                    app.VolumeEditField ...
                    app.WidthEditField ...
                    app.CorevolumeEditField ...
                    app.FeedercountEditField ...
                    app.SurfaceareaEditField ...
                    app.PartvolumeEditField ...
                    app.WeightEditField ...
                    };
                geometry_edit_fields = containers.Map( geometry_tags, geometry_values );
                gc = GeometryController( ...
                    cam, ...
                    tm, ...
                    cm, ...
                    geometry_edit_fields ...
                    );
                
                order_tags = cellstr( [ ...
                    OrderController.CLASS ...
                    OrderController.GRADE ...
                    OrderController.MATERIAL ...
                    OrderController.QUANTITY_ORDERED ...
                    ] );
                order_values = { ...
                    app.ClassDropDown ...
                    app.GradeDropDown ...
                    app.MaterialDropDown ...
                    app.QuantityEditField ...
                    };
                order_edit_fields = containers.Map( order_tags, order_values );
                oc = OrderController( ...
                    cam, ...
                    order_edit_fields ...
                    );
                
                pc = PathController( ...
                    tm, ...
                    app.FilepathStateLampLabel, ...
                    app.FilepathStateLamp, ...
                    app.LoadfileButton, ...
                    app.ProcessfileButton, ...
                    app.SaveanalysisButton ...
                    );
                
                sc = SettingController( ...
                    tm, ...
                    app.MeshElementsEditField, ...
                    app.ThermalProfileCheckBox ...
                    );
                
                ec = ExportController( oc, cc, sc, tm, cam );
            catch e
                disp( getReport( e ) );
                uiblockingalert( ...
                    app.UIFigure, ...
                    "Error while setting up. Unable to proceed, the application will now close.", ...
                    "Complexity Model Error" ...
                    );
                closereq();
                return;
            end
            
            % Set up app
            app.UIFigure.Name = "Cost Advisor Plus";
            
            app.cost_controller = cc;
            app.geometry_controller = gc;
            app.order_controller = oc;
            app.path_controller = pc;
            app.setting_controller = sc;
            app.export_controller = ec;
            
            drawnow();
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            app.order_controller.update();
            app.cost_controller.update();
            drawnow();
        end

        % Value changed function: GradeDropDown
        function GradeDropDownValueChanged(app, event)
            app.order_controller.update();
            app.cost_controller.update();
            drawnow();
        end

        % Value changed function: ClassDropDown
        function ClassDropDownValueChanged(app, event)
            app.order_controller.update();
            app.cost_controller.update();
            drawnow();
        end

        % Value changed function: QuantityEditField
        function QuantityEditFieldValueChanged(app, event)
            app.order_controller.update();
            app.cost_controller.update();
            drawnow();
        end

        % Callback function
        function FilepathEditFieldValueChanging(app, event)
            app.path_controller.update_input_file_path();
            drawnow();
        end

        % Button pushed function: ProcessfileButton
        function ProcessfileButtonPushed(app, event)
            try
                app.path_controller.process();
                app.geometry_controller.update();
                app.cost_controller.update();
                drawnow();
            catch e
                disp( getReport( e ) );
            end
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            try
                app.path_controller.load();
                app.geometry_controller.update();
                app.cost_controller.update();
                drawnow();
            catch e
                disp( getReport( e ) );
            end
        end

        % Value changed function: MeshElementsEditField
        function MeshElementsEditFieldValueChanged(app, event)
            app.setting_controller.update();
        end

        % Button pushed function: SaveanalysisButton
        function SaveanalysisButtonPushed(app, event)
            app.path_controller.save();
        end

        % Button pushed function: ExportresultsButton
        function ExportresultsButtonPushed(app, event)
            app.export_controller.export();
        end

        % Value changed function: ThermalProfileCheckBox
        function ThermalProfileCheckBoxValueChanged(app, event)
            app.setting_controller.update();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OrderPanel = uipanel(app.UIFigure);
            app.OrderPanel.Title = 'Order';
            app.MaterialSelectionLabel = uilabel(app.OrderPanel);
            app.MaterialSelectionLabel.Text = 'Material:';
            app.MaterialDropDown = uidropdown(app.OrderPanel);
            app.MaterialDropDown.Items = {};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = {};
            app.GradeDropDownLabel = uilabel(app.OrderPanel);
            app.GradeDropDownLabel.Text = 'Grade:';
            app.GradeDropDown = uidropdown(app.OrderPanel);
            app.GradeDropDown.Items = {};
            app.GradeDropDown.ValueChangedFcn = createCallbackFcn(app, @GradeDropDownValueChanged, true);
            app.GradeDropDown.Value = {};
            app.ClassLabel = uilabel(app.OrderPanel);
            app.ClassLabel.Text = 'Class:';
            app.ClassDropDown = uidropdown(app.OrderPanel);
            app.ClassDropDown.Items = {};
            app.ClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassDropDownValueChanged, true);
            app.ClassDropDown.Value = {};
            app.QuantityEditFieldLabel = uilabel(app.OrderPanel);
            app.QuantityEditFieldLabel.Text = 'Quantity:';
            app.QuantityEditField = uieditfield(app.OrderPanel, 'numeric');
            app.QuantityEditField.LowerLimitInclusive = 'off';
            app.QuantityEditField.Limits = [0 Inf];
            app.QuantityEditField.ValueDisplayFormat = '%i';
            app.QuantityEditField.ValueChangedFcn = createCallbackFcn(app, @QuantityEditFieldValueChanged, true);
            app.QuantityEditField.Value = 1;
            app.ModelPanel = uipanel(app.UIFigure);
            app.ModelPanel.Title = 'Model';
            app.LoadfileButton = uibutton(app.ModelPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file...';
            app.FilepathStateLampLabel = uilabel(app.ModelPanel);
            app.FilepathStateLampLabel.Text = 'FilepathStateLampLabel';
            app.FilepathStateLamp = uilamp(app.ModelPanel);
            app.ProcessfileButton = uibutton(app.ModelPanel, 'push');
            app.ProcessfileButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessfileButtonPushed, true);
            app.ProcessfileButton.Text = 'Process file...';
            app.BoxDimensionsPanel = uipanel(app.ModelPanel);
            app.BoxDimensionsPanel.Title = 'Box Dimensions';
            app.LengthEditFieldLabel = uilabel(app.BoxDimensionsPanel);
            app.LengthEditFieldLabel.Text = 'Length:';
            app.LengthEditField = uieditfield(app.BoxDimensionsPanel, 'numeric');
            app.LengthEditField.LowerLimitInclusive = 'off';
            app.LengthEditField.UpperLimitInclusive = 'off';
            app.LengthEditField.Limits = [0 Inf];
            app.LengthEditField.ValueDisplayFormat = '%.3g';
            app.LengthEditField.Editable = 'off';
            app.LengthEditField.Value = 1;
            app.WidthEditFieldLabel = uilabel(app.BoxDimensionsPanel);
            app.WidthEditFieldLabel.Text = 'Width:';
            app.WidthEditField = uieditfield(app.BoxDimensionsPanel, 'numeric');
            app.WidthEditField.LowerLimitInclusive = 'off';
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.ValueDisplayFormat = '%.3g';
            app.WidthEditField.Editable = 'off';
            app.WidthEditField.Value = 1;
            app.HeightEditFieldLabel = uilabel(app.BoxDimensionsPanel);
            app.HeightEditFieldLabel.Text = 'Height:';
            app.HeightEditField = uieditfield(app.BoxDimensionsPanel, 'numeric');
            app.HeightEditField.LowerLimitInclusive = 'off';
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueDisplayFormat = '%.3g';
            app.HeightEditField.Editable = 'off';
            app.HeightEditField.Value = 1;
            app.VolumeEditFieldLabel = uilabel(app.BoxDimensionsPanel);
            app.VolumeEditFieldLabel.Text = 'Volume:';
            app.VolumeEditField = uieditfield(app.BoxDimensionsPanel, 'numeric');
            app.VolumeEditField.LowerLimitInclusive = 'off';
            app.VolumeEditField.Limits = [0 Inf];
            app.VolumeEditField.ValueDisplayFormat = '%.3g';
            app.VolumeEditField.Editable = 'off';
            app.VolumeEditField.Value = 1;
            app.GeometryPanel = uipanel(app.ModelPanel);
            app.GeometryPanel.Title = 'Geometry';
            app.WeightEditFieldLabel = uilabel(app.GeometryPanel);
            app.WeightEditFieldLabel.Text = 'Weight:';
            app.WeightEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.WeightEditField.LowerLimitInclusive = 'off';
            app.WeightEditField.UpperLimitInclusive = 'off';
            app.WeightEditField.Limits = [0 Inf];
            app.WeightEditField.ValueDisplayFormat = '%.3g';
            app.WeightEditField.Editable = 'off';
            app.WeightEditField.Value = 1;
            app.SurfaceareaEditFieldLabel = uilabel(app.GeometryPanel);
            app.SurfaceareaEditFieldLabel.Text = 'Surface area:';
            app.SurfaceareaEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SurfaceareaEditField.LowerLimitInclusive = 'off';
            app.SurfaceareaEditField.UpperLimitInclusive = 'off';
            app.SurfaceareaEditField.Limits = [0 Inf];
            app.SurfaceareaEditField.ValueDisplayFormat = '%.3g';
            app.SurfaceareaEditField.Editable = 'off';
            app.SurfaceareaEditField.Value = 1;
            app.PartvolumeEditFieldLabel = uilabel(app.GeometryPanel);
            app.PartvolumeEditFieldLabel.Text = 'Part volume:';
            app.PartvolumeEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.PartvolumeEditField.LowerLimitInclusive = 'off';
            app.PartvolumeEditField.UpperLimitInclusive = 'off';
            app.PartvolumeEditField.Limits = [0 Inf];
            app.PartvolumeEditField.ValueDisplayFormat = '%.3g';
            app.PartvolumeEditField.Editable = 'off';
            app.PartvolumeEditField.Value = 1;
            app.CorevolumeEditFieldLabel = uilabel(app.GeometryPanel);
            app.CorevolumeEditFieldLabel.Text = 'Core volume:';
            app.CorevolumeEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.CorevolumeEditField.UpperLimitInclusive = 'off';
            app.CorevolumeEditField.Limits = [0 Inf];
            app.CorevolumeEditField.ValueDisplayFormat = '%.3g';
            app.CorevolumeEditField.Editable = 'off';
            app.RiggingPanel = uipanel(app.ModelPanel);
            app.RiggingPanel.Title = 'Rigging';
            app.FeedercountEditFieldLabel = uilabel(app.RiggingPanel);
            app.FeedercountEditFieldLabel.Text = 'Feeder count:';
            app.FeedercountEditField = uieditfield(app.RiggingPanel, 'numeric');
            app.FeedercountEditField.UpperLimitInclusive = 'off';
            app.FeedercountEditField.Limits = [0 Inf];
            app.FeedercountEditField.ValueDisplayFormat = '%i';
            app.FeedercountEditField.Editable = 'off';
            app.SaveanalysisButton = uibutton(app.ModelPanel, 'push');
            app.SaveanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveanalysisButtonPushed, true);
            app.SaveanalysisButton.Text = 'Save analysis...';
            app.CostPanel = uipanel(app.UIFigure);
            app.CostPanel.Title = 'Cost';
            app.PerPartLabel = uilabel(app.CostPanel);
            app.PerPartLabel.Text = 'Per Part';
            app.PerOrderLabel = uilabel(app.CostPanel);
            app.PerOrderLabel.Text = 'Per Order';
            app.TotalLabel = uilabel(app.CostPanel);
            app.TotalLabel.Text = 'Total';
            app.PartLabel = uilabel(app.CostPanel);
            app.PartLabel.Text = 'Part';
            app.MaterialCostLabel = uilabel(app.CostPanel);
            app.MaterialCostLabel.Text = 'Material';
            app.ProcessingLabel = uilabel(app.CostPanel);
            app.ProcessingLabel.Text = 'Processing';
            app.NDTLabel = uilabel(app.CostPanel);
            app.NDTLabel.Text = 'NDT';
            app.ToolingLabel = uilabel(app.CostPanel);
            app.ToolingLabel.Text = 'Tooling';
            app.FixturingLabel = uilabel(app.CostPanel);
            app.FixturingLabel.Text = 'Fixturing';
            app.StraighteningLabel = uilabel(app.CostPanel);
            app.StraighteningLabel.Text = 'Straightening';
            app.CheckLabel = uilabel(app.CostPanel);
            app.CheckLabel.Text = 'Check';
            app.GrandTotalPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.GrandTotalPerPartEditField.UpperLimitInclusive = 'off';
            app.GrandTotalPerPartEditField.Limits = [0 Inf];
            app.GrandTotalPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.GrandTotalPerPartEditField.Editable = 'off';
            app.GrandTotalPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.GrandTotalPerOrderEditField.UpperLimitInclusive = 'off';
            app.GrandTotalPerOrderEditField.Limits = [0 Inf];
            app.GrandTotalPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.GrandTotalPerOrderEditField.Editable = 'off';
            app.PartSubtotalPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.PartSubtotalPerPartEditField.UpperLimitInclusive = 'off';
            app.PartSubtotalPerPartEditField.Limits = [0 Inf];
            app.PartSubtotalPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.PartSubtotalPerPartEditField.Editable = 'off';
            app.PartSubtotalPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.PartSubtotalPerOrderEditField.UpperLimitInclusive = 'off';
            app.PartSubtotalPerOrderEditField.Limits = [0 Inf];
            app.PartSubtotalPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.PartSubtotalPerOrderEditField.Editable = 'off';
            app.MaterialPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.MaterialPerPartEditField.UpperLimitInclusive = 'off';
            app.MaterialPerPartEditField.Limits = [0 Inf];
            app.MaterialPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.MaterialPerPartEditField.Editable = 'off';
            app.MaterialPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.MaterialPerOrderEditField.UpperLimitInclusive = 'off';
            app.MaterialPerOrderEditField.Limits = [0 Inf];
            app.MaterialPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.MaterialPerOrderEditField.Editable = 'off';
            app.ProcessingPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.ProcessingPerPartEditField.UpperLimitInclusive = 'off';
            app.ProcessingPerPartEditField.Limits = [0 Inf];
            app.ProcessingPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.ProcessingPerPartEditField.Editable = 'off';
            app.ProcessingPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.ProcessingPerOrderEditField.UpperLimitInclusive = 'off';
            app.ProcessingPerOrderEditField.Limits = [0 Inf];
            app.ProcessingPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.ProcessingPerOrderEditField.Editable = 'off';
            app.NdtPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.NdtPerPartEditField.UpperLimitInclusive = 'off';
            app.NdtPerPartEditField.Limits = [0 Inf];
            app.NdtPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.NdtPerPartEditField.Editable = 'off';
            app.NdtPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.NdtPerOrderEditField.UpperLimitInclusive = 'off';
            app.NdtPerOrderEditField.Limits = [0 Inf];
            app.NdtPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.NdtPerOrderEditField.Editable = 'off';
            app.ToolingPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.ToolingPerPartEditField.UpperLimitInclusive = 'off';
            app.ToolingPerPartEditField.Limits = [0 Inf];
            app.ToolingPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.ToolingPerPartEditField.Editable = 'off';
            app.ToolingPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.ToolingPerOrderEditField.UpperLimitInclusive = 'off';
            app.ToolingPerOrderEditField.Limits = [0 Inf];
            app.ToolingPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.ToolingPerOrderEditField.Editable = 'off';
            app.FixturingSubtotalPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.FixturingSubtotalPerPartEditField.UpperLimitInclusive = 'off';
            app.FixturingSubtotalPerPartEditField.Limits = [0 Inf];
            app.FixturingSubtotalPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.FixturingSubtotalPerPartEditField.Editable = 'off';
            app.FixturingSubtotalPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.FixturingSubtotalPerOrderEditField.UpperLimitInclusive = 'off';
            app.FixturingSubtotalPerOrderEditField.Limits = [0 Inf];
            app.FixturingSubtotalPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.FixturingSubtotalPerOrderEditField.Editable = 'off';
            app.StraighteningPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.StraighteningPerPartEditField.UpperLimitInclusive = 'off';
            app.StraighteningPerPartEditField.Limits = [0 Inf];
            app.StraighteningPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.StraighteningPerPartEditField.Editable = 'off';
            app.StraighteningPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.StraighteningPerOrderEditField.UpperLimitInclusive = 'off';
            app.StraighteningPerOrderEditField.Limits = [0 Inf];
            app.StraighteningPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.StraighteningPerOrderEditField.Editable = 'off';
            app.CheckPerPartEditField = uieditfield(app.CostPanel, 'numeric');
            app.CheckPerPartEditField.UpperLimitInclusive = 'off';
            app.CheckPerPartEditField.Limits = [0 Inf];
            app.CheckPerPartEditField.ValueDisplayFormat = '$%.2f';
            app.CheckPerPartEditField.Editable = 'off';
            app.CheckPerOrderEditField = uieditfield(app.CostPanel, 'numeric');
            app.CheckPerOrderEditField.UpperLimitInclusive = 'off';
            app.CheckPerOrderEditField.Limits = [0 Inf];
            app.CheckPerOrderEditField.ValueDisplayFormat = '$%.2f';
            app.CheckPerOrderEditField.Editable = 'off';
            app.GrandTotalPerPartUpperCI = uieditfield(app.CostPanel, 'numeric');
            app.GrandTotalPerPartUpperCI.ValueDisplayFormat = '+$%.2f';
            app.GrandTotalPerPartUpperCI.Editable = 'off';
            app.GrandTotalPerPartLowerCI = uieditfield(app.CostPanel, 'numeric');
            app.GrandTotalPerPartLowerCI.ValueDisplayFormat = '-$%.2f';
            app.GrandTotalPerPartLowerCI.Editable = 'off';
            app.GrandTotalPerOrderUpperCI = uieditfield(app.CostPanel, 'numeric');
            app.GrandTotalPerOrderUpperCI.ValueDisplayFormat = '+$%.2f';
            app.GrandTotalPerOrderUpperCI.Editable = 'off';
            app.GrandTotalPerOrderLowerCI = uieditfield(app.CostPanel, 'numeric');
            app.GrandTotalPerOrderLowerCI.ValueDisplayFormat = '-$%.2f';
            app.GrandTotalPerOrderLowerCI.Editable = 'off';
            app.ExportresultsButton = uibutton(app.CostPanel, 'push');
            app.ExportresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportresultsButtonPushed, true);
            app.ExportresultsButton.Text = 'Export results...';
            app.ProgramSettingsPanel = uipanel(app.UIFigure);
            app.ProgramSettingsPanel.Title = 'Program Settings';
            app.MeshElementsEditFieldLabel = uilabel(app.ProgramSettingsPanel);
            app.MeshElementsEditFieldLabel.Text = 'Mesh Elements:';
            app.MeshElementsEditField = uieditfield(app.ProgramSettingsPanel, 'numeric');
            app.MeshElementsEditField.UpperLimitInclusive = 'off';
            app.MeshElementsEditField.Limits = [1 Inf];
            app.MeshElementsEditField.ValueDisplayFormat = '%.2g';
            app.MeshElementsEditField.ValueChangedFcn = createCallbackFcn(app, @MeshElementsEditFieldValueChanged, true);
            app.MeshElementsEditField.Value = 1000000;
            app.ThermalProfileCheckBox = uicheckbox(app.ProgramSettingsPanel);
            app.ThermalProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThermalProfileCheckBoxValueChanged, true);
            app.ThermalProfileCheckBox.Text = 'Thermal Profile';
        end
    end

    methods (Access = public)

        % Construct app
        function app = cost_advisor_plus

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end